classdef mainApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PorousLabUIFigure     matlab.ui.Figure
        PhysicsDropDownLabel  matlab.ui.control.Label
        CreatemodelButton     matlab.ui.control.Button
        Image                 matlab.ui.control.Image
        PhysicsDropDown       matlab.ui.control.DropDown
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            close all; clc;

            % Path to source directory
            src_dir = fullfile(fileparts(mfilename('fullpath')), '..', 'src');
            addpath(genpath(src_dir));
            
            print_header;

        end

        % Button pushed function: CreatemodelButton
        function CreatemodelButtonPushed(app, event)
            
            if strcmp(app.PhysicsDropDown.Value,'M')
                mdl = Model_M();
            elseif strcmp(app.PhysicsDropDown.Value,'H')
                mdl = Model_H();
            elseif strcmp(app.PhysicsDropDown.Value,'HM')
                mdl = Model_HM();
            elseif strcmp(app.PhysicsDropDown.Value,'H2')
                mdl = Model_H2();
            end
            meshOptions(mdl);
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.PorousLabUIFigure = uifigure('Visible', 'off');
            app.PorousLabUIFigure.Name = 'PorousLab';
            app.PorousLabUIFigure.Icon = fullfile(pathToMLAPP, 'logo_reduced_porouslab_vertical.png');
            app.PhysicsDropDown = uidropdown(app.PorousLabUIFigure);
            app.PhysicsDropDown.Items = {'M', 'H', 'H2', 'HM', 'H2M'};
            app.PhysicsDropDown.Value = 'M';
            app.Image = uiimage(app.PorousLabUIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'logo_porouslab_vertical.png');
            app.CreatemodelButton = uibutton(app.PorousLabUIFigure, 'push');
            app.CreatemodelButton.ButtonPushedFcn = createCallbackFcn(app, @CreatemodelButtonPushed, true);
            app.CreatemodelButton.Text = 'Create model';
            app.PhysicsDropDownLabel = uilabel(app.PorousLabUIFigure);
            app.PhysicsDropDownLabel.Text = 'Physics';
            % Show the figure after all components are created
            app.PorousLabUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mainApp

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.PorousLabUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.PorousLabUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PorousLabUIFigure)
        end
    end
end