classdef mechanicalModel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PorousLabMechanicalModelUIFigure  matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        DirichletBCsTab                 matlab.ui.container.Tab
        ApplyButton                     matlab.ui.control.Button
        DropDownDirichletBC             matlab.ui.control.DropDown
        FixedLabel                      matlab.ui.control.Label
        CheckFixedUy                    matlab.ui.control.CheckBox
        CheckFixedUx                    matlab.ui.control.CheckBox
        ClearButton                     matlab.ui.control.Button
        yEditField                      matlab.ui.control.NumericEditField
        yEditFieldLabel                 matlab.ui.control.Label
        xEditField                      matlab.ui.control.NumericEditField
        xEditFieldLabel                 matlab.ui.control.Label
        PrescribedvaluesLabel           matlab.ui.control.Label
        UxEditField                     matlab.ui.control.NumericEditField
        UxEditFieldLabel                matlab.ui.control.Label
        UyEditField                     matlab.ui.control.NumericEditField
        UyEditFieldLabel                matlab.ui.control.Label
        SelecttheborderDropDownLabel    matlab.ui.control.Label
        SelecttheborderDropDown         matlab.ui.control.DropDown
        SetdisplacementatLabel          matlab.ui.control.Label
        UIAxesDirichlet                 matlab.ui.control.UIAxes
        NeumannBCsTab                   matlab.ui.container.Tab
        BodyforcesDropDown              matlab.ui.control.DropDown
        BodyforcesDropDownLabel         matlab.ui.control.Label
        DropDownNeumannBC               matlab.ui.control.DropDown
        ApplyButtonNeumann              matlab.ui.control.Button
        FxEditField                     matlab.ui.control.NumericEditField
        FxEditFieldLabel                matlab.ui.control.Label
        yEditField_Neumann              matlab.ui.control.NumericEditField
        yEditField_2Label               matlab.ui.control.Label
        xEditField_Neumann              matlab.ui.control.NumericEditField
        xEditField_2Label               matlab.ui.control.Label
        FyEditField                     matlab.ui.control.NumericEditField
        FyEditFieldLabel                matlab.ui.control.Label
        BorderDropDown_Neumann          matlab.ui.control.DropDown
        SelecttheborderLabel            matlab.ui.control.Label
        ClearButton_Neumann             matlab.ui.control.Button
        LoadNLabel                      matlab.ui.control.Label
        SetloadatLabel                  matlab.ui.control.Label
        UIAxes_Neumann                  matlab.ui.control.UIAxes
        PorousmediaTab                  matlab.ui.container.Tab
        UpdatematerialButton            matlab.ui.control.Button
        MaterialmodelDropDown           matlab.ui.control.DropDown
        MaterialmodelDropDownLabel      matlab.ui.control.Label
        UITableMaterial                 matlab.ui.control.Table
        AnalysisTab                     matlab.ui.container.Tab
        TextArea                        matlab.ui.control.TextArea
        MethodDropDown                  matlab.ui.control.DropDown
        MethodDropDownLabel             matlab.ui.control.Label
        NonlinearquasistaticanalysisparametersLabel  matlab.ui.control.Label
        TargetnumberofiterationsEditField  matlab.ui.control.NumericEditField
        TargetnumberofiterationsEditFieldLabel  matlab.ui.control.Label
        MaximumincrementEditField       matlab.ui.control.NumericEditField
        MaximumincrementEditFieldLabel  matlab.ui.control.Label
        InitialincrementEditField       matlab.ui.control.NumericEditField
        InitialincrementEditFieldLabel  matlab.ui.control.Label
        MaximumnumberofiterationsEditField  matlab.ui.control.NumericEditField
        MaximumnumberofiterationsEditFieldLabel  matlab.ui.control.Label
        MaximumnumberofstepsEditField   matlab.ui.control.NumericEditField
        MaximumnumberofstepsEditFieldLabel  matlab.ui.control.Label
        MaximumloadratioEditField       matlab.ui.control.NumericEditField
        MaximumloadratioEditFieldLabel  matlab.ui.control.Label
        RunButton                       matlab.ui.control.Button
        UpdateanalysisButton            matlab.ui.control.Button
        AnalysistypeDropDown_2          matlab.ui.control.DropDown
        AnalysistypeDropDown_2Label     matlab.ui.control.Label
        PostProcessTab                  matlab.ui.container.Tab
        PlotaprofileCheckBox            matlab.ui.control.CheckBox
        maxEditField                    matlab.ui.control.NumericEditField
        maxEditFieldLabel               matlab.ui.control.Label
        minEditField                    matlab.ui.control.NumericEditField
        minEditFieldLabel               matlab.ui.control.Label
        ColorbarlimitsDropDown          matlab.ui.control.DropDown
        ColorbarlimitsDropDownLabel     matlab.ui.control.Label
        ExportmodelButton               matlab.ui.control.Button
        UITableResultProfile            matlab.ui.control.Table
        FieldDropDown                   matlab.ui.control.DropDown
        FieldDropDownLabel              matlab.ui.control.Label
        UIAxesResultFieldBig            matlab.ui.control.UIAxes
        UIAxesResultFieldSmall          matlab.ui.control.UIAxes
        UIAxesResultProfile             matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        mdl; % Object from the Model_M class
        anl; % Analysis object
    end

    
    methods (Access = private)
        
        %------------------------------------------------------------------
        % Plot the mesh
        function plotMesh(app,ax)
            % Convert the element cell into an array
            maxFaces = max(cellfun(@numel, app.mdl.ELEM));
            % Get only the corner nodes
            if maxFaces == 6 || maxFaces == 8
                maxFaces = maxFaces / 2;
            end
            faces = NaN(numel(app.mdl.ELEM), maxFaces); 
            for i = 1:numel(app.mdl.ELEM)
                enlinnodes = numel(app.mdl.ELEM{i});
                if enlinnodes == 6 || enlinnodes == 8
                    enlinnodes = enlinnodes / 2;
                end
                faces(i, 1:enlinnodes) = app.mdl.ELEM{i}(1:enlinnodes); 
            end
            % Plot
            cla(ax);
            patch(ax, 'Faces', faces, ...
                      'Vertices', app.mdl.NODE,...
                      'FaceColor', 'g', ...  
                      'LineWidth', 0.7, ...
                      'LineStyle', '-', ...
                      'Marker', 'None');
            bbox = app.getBoundingBox();
            xlim(ax, [bbox(1) bbox(3)]);
            ylim(ax, [bbox(2) bbox(4)]);
            axis(ax, 'equal');
            hold(ax, 'on');
        end

        %------------------------------------------------------------------
        % Computes the bounding box of the model with a tolerance.
        function bbox = getBoundingBox(app,tol)
            if nargin < 2, tol = 0.1; end
            minX = Inf; minY = Inf; maxX = -Inf; maxY = -Inf;
            for el = 1:size(app.mdl.ELEM,1)
                minX = min(minX,min(app.mdl.NODE(:,1)));
                minY = min(minY,min(app.mdl.NODE(:,2)));
                maxX = max(maxX,max(app.mdl.NODE(:,1)));
                maxY = max(maxY,max(app.mdl.NODE(:,2)));
            end
            tolx = tol*abs(max(app.mdl.NODE(:,1)) - min(app.mdl.NODE(:,1)));
            toly = tol*abs(max(app.mdl.NODE(:,1)) - min(app.mdl.NODE(:,1)));
            bbox(1) = minX - tolx;
            bbox(2) = minY - toly;
            bbox(3) = maxX + tolx;
            bbox(4) = maxY + toly;
        end

        %------------------------------------------------------------------
        % Computes the bounding box of the model with a tolerance.
        function plotPoint(~,ax,X,mtype)
            if nargin < 4, mtype = "diamond"; end
            plot(ax,X(1),X(2),'Marker',mtype,...
                'MarkerEdgeColor','k',...
                'MarkerFaceColor','k');
        end

        %------------------------------------------------------------------
        function plotDirichletLegend(app)
            ax = app.UIAxesDirichlet;
            % Create dummy plots just for the legend
            h2 = plot(ax, NaN, NaN, 'k^','MarkerFaceColor','k', 'DisplayName', 'Uy fixed');
            h3 = plot(ax, NaN, NaN, 'k>','MarkerFaceColor','k', 'DisplayName', 'Ux fixed');
            h4 = plot(ax, NaN, NaN, 'kd','MarkerFaceColor','k', 'DisplayName', 'Ux & Uy fixed');
            
            % Force legend to only use these handles
            legend(ax, [h2 h3 h4], 'Location', 'best');
        end

        %------------------------------------------------------------------
        function plotNeumannLegend(app)
            ax = app.UIAxes_Neumann;
            % Create dummy plots just for the legend
            h2 = plot(ax, NaN, NaN, 'k^','MarkerFaceColor','k', 'DisplayName', 'Fy');
            h3 = plot(ax, NaN, NaN, 'k>','MarkerFaceColor','k', 'DisplayName', 'Fx');
            h4 = plot(ax, NaN, NaN, 'kd','MarkerFaceColor','k', 'DisplayName', 'Fx and Fy');
            
            % Force legend to only use these handles
            legend(ax, [h2 h3 h4], 'Location', 'best');
        end

        %------------------------------------------------------------------
        function plotField(app)
            if app.PlotaprofileCheckBox.Value == 1
                % Remove big plot field
                app.UIAxesResultFieldBig.Visible = "off"; 
                cla(app.UIAxesResultFieldBig);
                delete(findall(app.PorousLabMechanicalModelUIFigure, 'Type', 'ColorBar'));
                % Plot small field
                app.UIAxesResultFieldSmall.Visible = "on";
                app.mdl.plotField(app.FieldDropDown.Value,[],app.UIAxesResultFieldSmall);
                % Plot profile
                Xi = [app.UITableResultProfile.Data(1,1);
                      app.UITableResultProfile.Data(1,2)];
                Xf = [app.UITableResultProfile.Data(2,1);
                      app.UITableResultProfile.Data(2,2)];
                app.mdl.plotFieldAlongSegment(app.FieldDropDown.Value, Xi, Xf, 10, 'x' ,app.UIAxesResultProfile)
                % Make components visible
                app.UIAxesResultProfile.Visible = "on";
                app.UITableResultProfile.Visible = "on";
            else
                % Clear small plot field
                app.UIAxesResultFieldSmall.Visible = "off";
                cla(app.UIAxesResultFieldSmall);
                delete(findall(app.PorousLabMechanicalModelUIFigure, 'Type', 'ColorBar'));
                % Use the big field plot
                app.UIAxesResultFieldBig.Visible = "on";
                app.mdl.plotField(app.FieldDropDown.Value,[],app.UIAxesResultFieldBig);
                % Change visibility
                cla(app.UIAxesResultProfile);
                app.UIAxesResultProfile.Visible = "off";
                app.UITableResultProfile.Visible = "off";
            end
            app.ColorbarlimitsDropDown.Visible = "on";
            app.ColorbarlimitsDropDownLabel.Visible = "on";
            app.PlotaprofileCheckBox.Visible = "on";
        end

        %------------------------------------------------------------------
        function updateColorBar(app)
            range = [app.minEditField.Value; app.maxEditField.Value];
            if app.PlotaprofileCheckBox.Value == 1
                c = colorbar(app.UIAxesResultFieldSmall);         
                c.Limits = range;
                clim(app.UIAxesResultFieldSmall, range);
            else
                c = colorbar(app.UIAxesResultFieldBig);         
                c.Limits = range;
                clim(app.UIAxesResultFieldBig, range);
            end
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mdl)
            t = readtable('data/mat_mec_linearElastic.csv');
            app.UITableMaterial.Data = t;
            app.mdl = mdl;
            app.plotMesh(app.UIAxesDirichlet);
            app.plotMesh(app.UIAxes_Neumann);
            % Initialize material
            rock = PorousMedia('rock');
            rock.mechanical = 'elastic';
            rock.Young      = app.UITableMaterial.Data{1,2};
            rock.nu         = app.UITableMaterial.Data{2,2};
            rock.rho        = app.UITableMaterial.Data{3,2};
            app.mdl.setMaterial(rock);
            % Initialize analysis
            app.anl = Anl_Linear();
            % Initialize the post-processing data
            bb = app.getBoundingBox(0.0);
            app.UITableResultProfile.Data = [bb(1),0.5*(bb(2)+bb(4));
                                             bb(3),0.5*(bb(2)+bb(4))];
        end

        % Callback function
        function PrescribevaluesatnodeButtonPushed(app, event)
            % Get coordinates of the node
            x = app.xEditField.Value;
            y = app.yEditField.Value;

            % Get prescribed displacement values
            ux = app.UxEditField.Value;
            uy = app.UyEditField.Value;

            % Free dofs
            if (app.CheckFixedUx.Value==0)
                ux = NaN;
            end
            if (app.CheckFixedUy.Value==0)
                uy = NaN;
            end

            % Add value
            app.mdl.setDisplacementDirichletBCAtPoint([x,y],[ux,uy]);
            app.plotPoint(app.UIAxesDirichlet,[x,y]);
        end

        % Callback function
        function PrescribevaluesatborderButtonPushed(app, event)
            % Get prescribed displacement values
            ux = app.UxEditField.Value;
            uy = app.UyEditField.Value;

            % Free dofs
            if (app.CheckFixedUx.Value==0)
                ux = NaN;
            end
            if (app.CheckFixedUy.Value==0)
                uy = NaN;
            end

            % Border
            border = app.SelecttheborderDropDown.Value;

            % Add value
            app.mdl.setDisplacementDirichletBCAtBorder(border,[ux,uy]);

            % Plot nodes
            nodeIds = app.mdl.getNodesAtBorder(border);
            for i = 1:length(nodeIds)
                app.plotPoint(app.UIAxesDirichlet,app.mdl.NODE(nodeIds(i),:));
            end
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.mdl.DIRICHLET_TAG = zeros(app.mdl.nnodes,app.mdl.ndof_nd);
            app.mdl.DIRICHLET_VAL = zeros(app.mdl.nnodes,app.mdl.ndof_nd);
            app.plotMesh(app.UIAxesDirichlet);
        end

        % Callback function
        function ApplyloadatnodeButtonPushed(app, event)
            % Get coordinates of the node
            x = app.xEditField_Neumann.Value;
            y = app.yEditField_Neumann.Value;

            % Get load values
            Fx = app.FxEditField.Value;
            Fy = app.FyEditField.Value;

            % Add value
            app.mdl.addLoadAtPoint([x,y],[Fx,Fy]);
            app.plotPoint(app.UIAxes_Neumann,[x,y]);
        end

        % Callback function
        function ApplyloadatborderButtonPushed(app, event)

            % Get load values
            Fx = app.FxEditField.Value;
            Fy = app.FyEditField.Value;

            % Border
            border = app.BorderDropDown_Neumann.Value;

            % Add value
            if Fx > 0
                app.mdl.addLoadAtBorder(border,1,Fx);
            end
            if Fy > 0
                app.mdl.addLoadAtBorder(border,2,Fy);
            end

            % Plot nodes
            nodeIds = app.mdl.getNodesAtBorder(border);
            for i = 1:length(nodeIds)
                app.plotPoint(app.UIAxes_Neumann,app.mdl.NODE(nodeIds(i),:));
            end
        end

        % Button pushed function: ClearButton_Neumann
        function ClearButton_NeumannPushed(app, event)
            app.mdl.LOAD = zeros(app.mdl.nnodes,app.mdl.ndof_nd);
            app.plotMesh(app.UIAxes_Neumann);
        end

        % Button pushed function: UpdatematerialButton
        function UpdatematerialButtonPushed(app, event)
            rock = PorousMedia('rock');
            if strcmp(app.MaterialmodelDropDown.Value,'Linear Elastic')
                rock.mechanical = 'elastic';
                rock.Young      = app.UITableMaterial.Data{1,2};
                rock.nu         = app.UITableMaterial.Data{2,2};
                rock.rho        = app.UITableMaterial.Data{3,2};
            elseif strcmp(app.MaterialmodelDropDown.Value,'von Mises')
                rock.mechanical = 'vonMises';
                rock.Young      = app.UITableMaterial.Data{1,2};
                rock.nu         = app.UITableMaterial.Data{2,2};
                rock.rho        = app.UITableMaterial.Data{3,2};
                rock.sy0        = app.UITableMaterial.Data{4,2};
                rock.Kp         = 0.0;         % Plastic modulus (Pa)
            elseif strcmp(app.MaterialmodelDropDown.Value,'Drucker-Prager')
                rock.mechanical = 'druckerPrager';
                rock.Young         = app.UITableMaterial.Data{1,2};
                rock.nu            = app.UITableMaterial.Data{2,2};
                rock.rho           = app.UITableMaterial.Data{3,2};
                rock.cohesion      = app.UITableMaterial.Data{4,2};
                rock.frictionAngle = app.UITableMaterial.Data{5,2}*pi/180;
                rock.dilationAngle = app.UITableMaterial.Data{6,2}*pi/180;
                rock.MCmatch       = "planestrain";    % How the Drucker-Prager surfaces matches the Mohr-Coulomb
            elseif strcmp(app.MaterialmodelDropDown.Value,'Isotropic damage')
                rock.mechanical = 'isoDamage';
                rock.Young      = app.UITableMaterial.Data{1,2};
                rock.nu         = app.UITableMaterial.Data{2,2};
                rock.rho        = app.UITableMaterial.Data{3,2};
                rock.DamageThreshold     = app.UITableMaterial.Data{4,2};
                rock.FractureEnergyMode1 = app.UITableMaterial.Data{5,2};
                rock.kappa               = app.UITableMaterial.Data{6,2};
            end
            % Set materials to model
            app.mdl.setMaterial(rock);
        end

        % Button pushed function: ExportmodelButton
        function ExportmodelButtonPushed(app, event)
            mdl = app.mdl; 
            save(app.mdl.name,'mdl');
        end

        % Button pushed function: UpdateanalysisButton
        function UpdateanalysisButtonPushed(app, event)
           if strcmp(app.AnalysistypeDropDown_2.Value,'Linear')
               app.anl = Anl_Linear();
           elseif strcmp(app.AnalysistypeDropDown_2.Value,'Quasi-static')
               app.anl = Anl_NonlinearQuasiStatic();
               app.anl.trg_iter = app.TargetnumberofiterationsEditField.Value;
               app.anl.max_increment = app.MaximumincrementEditField.Value;
               app.anl.increment = app.InitialincrementEditField.Value;
               app.anl.max_iter = app.MaximumnumberofiterationsEditField.Value;
               app.anl.max_step = app.MaximumnumberofstepsEditField.Value;
               app.anl.max_lratio = app.MaximumloadratioEditField.Value;
               if strcmp(app.MethodDropDown.Value,'Load Control')
                   app.anl.method = 'LoadControl';
               elseif strcmp(app.MethodDropDown.Value,'Cylindrical Arc Length')
                   app.anl.method = 'ArcLengthCylControl';
               elseif strcmp(app.MethodDropDown.Value,'Generalized Displacement')
                   app.anl.method = 'GeneralizedDisplacement';
               end
           end
        end

        % Value changed function: AnalysistypeDropDown_2
        function AnalysistypeDropDown_2ValueChanged(app, event)
            if strcmp(app.AnalysistypeDropDown_2.Value,'Linear')
                app.MethodDropDown.Visible = "off";
                app.MethodDropDownLabel.Visible = "off";
                app.NonlinearquasistaticanalysisparametersLabel.Visible = "off";
                app.TargetnumberofiterationsEditField.Visible = "off";
                app.TargetnumberofiterationsEditFieldLabel.Visible = "off";
                app.MaximumincrementEditField.Visible = "off";
                app.MaximumincrementEditFieldLabel.Visible = "off";
                app.InitialincrementEditField.Visible = "off";
                app.InitialincrementEditFieldLabel.Visible = "off";
                app.MaximumnumberofiterationsEditField.Visible = "off";
                app.MaximumnumberofiterationsEditFieldLabel.Visible = "off";
                app.MaximumnumberofstepsEditField.Visible = "off";
                app.MaximumnumberofstepsEditFieldLabel.Visible = "off";
                app.MaximumloadratioEditField.Visible = "off";
                app.MaximumloadratioEditFieldLabel.Visible = "off";
            elseif strcmp(app.AnalysistypeDropDown_2.Value,'Quasi-static')
                app.MethodDropDown.Visible = "on";
                app.MethodDropDownLabel.Visible = "on";
                app.NonlinearquasistaticanalysisparametersLabel.Visible = "on";
                app.TargetnumberofiterationsEditField.Visible = "on";
                app.TargetnumberofiterationsEditFieldLabel.Visible = "on";
                app.MaximumincrementEditField.Visible = "on";
                app.MaximumincrementEditFieldLabel.Visible = "on";
                app.InitialincrementEditField.Visible = "on";
                app.InitialincrementEditFieldLabel.Visible = "on";
                app.MaximumnumberofiterationsEditField.Visible = "on";
                app.MaximumnumberofiterationsEditFieldLabel.Visible = "on";
                app.MaximumnumberofstepsEditField.Visible = "on";
                app.MaximumnumberofstepsEditFieldLabel.Visible = "on";
                app.MaximumloadratioEditField.Visible = "on";
                app.MaximumloadratioEditFieldLabel.Visible = "on";
           end
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            app.mdl.resetModelState();
            runAnl = @() app.anl.run(app.mdl);
            app.TextArea.Value = evalc('runAnl()');
        end

        % Value changed function: FieldDropDown
        function FieldDropDownValueChanged(app, event)
            if ~strcmp(app.FieldDropDown.Value,'None')
                app.plotField();
            else
                app.ColorbarlimitsDropDown.Visible = "on";
                app.ColorbarlimitsDropDownLabel.Visible = "on";
                app.PlotaprofileCheckBox.Visible = "on";
                app.UIAxesResultFieldBig.Visible = "off";
                app.UIAxesResultFieldSmall.Visible = "off";
                app.UIAxesResultProfile.Visible = "off";
                app.UITableResultProfile.Visible = "off";
                cla(app.UIAxesResultFieldBig);
                cla(app.UIAxesResultFieldSmall);
                cla(app.UIAxesResultProfile);
                delete(findall(app.PorousLabMechanicalModelUIFigure, 'Type', 'ColorBar'));
            end
        end

        % Cell edit callback: UITableResultProfile
        function UITableResultProfileCellEdit(app, event)
            Xi = [app.UITableResultProfile.Data(1,1);
                      app.UITableResultProfile.Data(1,2)];
            Xf = [app.UITableResultProfile.Data(2,1);
                  app.UITableResultProfile.Data(2,2)];
            app.mdl.plotFieldAlongSegment(app.FieldDropDown.Value, Xi, Xf, 10, 'x' ,app.UIAxesResultProfile)
        end

        % Value changed function: MaterialmodelDropDown
        function MaterialmodelDropDownValueChanged(app, event)
            mat = app.MaterialmodelDropDown.Value;
            if strcmp(mat,'Linear Elastic')
                t = readtable('data/mat_mec_linearElastic.csv');
            elseif strcmp(mat,'von Mises')
                t = readtable('data/mat_mec_vonMises.csv');
            elseif strcmp(mat,'Drucker-Prager')
                t = readtable('data/mat_mec_druckerPrager.csv');
            elseif strcmp(mat,'Isotropic damage')
                t = readtable('data/mat_mec_isoDamage.csv');
            end
            app.UITableMaterial.Data = t;
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            % Get prescribed displacement values
            ux = app.UxEditField.Value;
            uy = app.UyEditField.Value;

            % Free dofs
            if (app.CheckFixedUx.Value==0)
                ux = NaN;
            end
            if (app.CheckFixedUy.Value==0)
                uy = NaN;
            end

            % Marker type
            if (app.CheckFixedUx.Value==0) && (app.CheckFixedUy.Value==0)
                markerType = "o";
            elseif (app.CheckFixedUx.Value==0) && (app.CheckFixedUy.Value==1)
                markerType = "^";
            elseif (app.CheckFixedUx.Value==1) && (app.CheckFixedUy.Value==0)
                markerType = ">";
            elseif (app.CheckFixedUx.Value==1) && (app.CheckFixedUy.Value==1)
                markerType = "diamond";
            end

            if strcmp(app.DropDownDirichletBC.Value,'node')
                % Get coordinates of the node
                x = app.xEditField.Value;
                y = app.yEditField.Value;
    
                % Add value
                app.mdl.setDisplacementDirichletBCAtPoint([x,y],[ux,uy]);
                app.plotPoint(app.UIAxesDirichlet,[x,y],markerType);

            elseif strcmp(app.DropDownDirichletBC.Value,'border')
                % Border
                border = app.SelecttheborderDropDown.Value;
    
                % Add value
                app.mdl.setDisplacementDirichletBCAtBorder(border,[ux,uy]);
    
                % Plot nodes
                nodeIds = app.mdl.getNodesAtBorder(border);
                for i = 1:length(nodeIds)
                    app.plotPoint(app.UIAxesDirichlet,app.mdl.NODE(nodeIds(i),:),markerType);
                end

            end

            app.plotDirichletLegend();
        end

        % Value changed function: DropDownDirichletBC
        function DropDownDirichletBCValueChanged(app, event)
            % Update visibilities
            if strcmp(app.DropDownDirichletBC.Value,'node')
                app.SelecttheborderDropDown.Visible = "off";
                app.SelecttheborderDropDownLabel.Visible = "off";
                app.xEditField.Visible = "on";
                app.yEditField.Visible = "on";
                app.xEditFieldLabel.Visible = "on";
                app.yEditFieldLabel.Visible = "on";
            elseif strcmp(app.DropDownDirichletBC.Value,'border')
                app.SelecttheborderDropDown.Visible = "on";
                app.SelecttheborderDropDownLabel.Visible = "on";
                app.xEditField.Visible = "off";
                app.yEditField.Visible = "off";
                app.xEditFieldLabel.Visible = "off";
                app.yEditFieldLabel.Visible = "off";
            end
        end

        % Button pushed function: ApplyButtonNeumann
        function ApplyButtonNeumannPushed(app, event)
            % Get load values
            Fx = app.FxEditField.Value;
            Fy = app.FyEditField.Value;

            % Marker type
            if (abs(Fx) == 0) && (abs(Fy) == 0)
                markerType = "o";
            elseif (abs(Fx) == 0) && (abs(Fy) > 0)
                markerType = "^";
            elseif (abs(Fx) > 0) && (abs(Fy) == 0)
                markerType = ">";
            elseif (abs(Fx) > 0) && (abs(Fy) > 0)
                markerType = "diamond";
            end

            if strcmp(app.DropDownNeumannBC.Value,'node')
                % Get coordinates of the node
                x = app.xEditField_Neumann.Value;
                y = app.yEditField_Neumann.Value;
                % Add value
                app.mdl.addLoadAtPoint([x,y],[Fx,Fy]);
                app.plotPoint(app.UIAxes_Neumann,[x,y],markerType);
            elseif strcmp(app.DropDownNeumannBC.Value,'border')
                % Border
                border = app.BorderDropDown_Neumann.Value;
                % Add value
                if abs(Fx) > 0
                    app.mdl.addLoadAtBorder(border,1,Fx);
                end
                if abs(Fy) > 0
                    app.mdl.addLoadAtBorder(border,2,Fy);
                end
                % Plot nodes
                nodeIds = app.mdl.getNodesAtBorder(border);
                for i = 1:length(nodeIds)
                    app.plotPoint(app.UIAxes_Neumann,app.mdl.NODE(nodeIds(i),:),markerType);
                end
            end
            app.plotNeumannLegend();
        end

        % Value changed function: DropDownNeumannBC
        function DropDownNeumannBCValueChanged(app, event)
            if strcmp(app.DropDownNeumannBC.Value,'node')
                app.LoadNLabel.Text  = "Load (N)";
                app.BorderDropDown_Neumann.Visible = "off";
                app.SelecttheborderLabel.Visible = "off";
                app.xEditField_Neumann.Visible = "on";
                app.yEditField_Neumann.Visible = "on";
                app.xEditField_2Label.Visible = "on";
                app.yEditField_2Label.Visible = "on";
            elseif strcmp(app.DropDownNeumannBC.Value,'border')
                app.LoadNLabel.Text  = "Load (N/m)";
                app.BorderDropDown_Neumann.Visible = "on";
                app.SelecttheborderLabel.Visible = "on";
                app.xEditField_Neumann.Visible = "off";
                app.yEditField_Neumann.Visible = "off";
                app.xEditField_2Label.Visible = "off";
                app.yEditField_2Label.Visible = "off";
            end  
        end

        % Value changed function: BodyforcesDropDown
        function BodyforcesDropDownValueChanged(app, event)
            % Set gravity
            if strcmp(app.BodyforcesDropDown.Value,'Yes')
                app.mdl.gravityOn = true;
            elseif strcmp(app.BodyforcesDropDown.Value,'No')
                app.mdl.gravityOn = false;
            end
        end

        % Value changed function: ColorbarlimitsDropDown
        function ColorbarlimitsDropDownValueChanged(app, event)
            if strcmp(app.ColorbarlimitsDropDown.Value,'default')
                app.maxEditField.Visible = "off";
                app.minEditField.Visible = "off";
                app.maxEditFieldLabel.Visible = "off";
                app.minEditFieldLabel.Visible = "off";
                % Reset color scaling to auto
                if app.PlotaprofileCheckBox.Value == 1
                    app.UIAxesResultFieldSmall.CLimMode = 'auto';
                    colorbar(app.UIAxesResultFieldSmall);
                else
                    app.UIAxesResultFieldBig.CLimMode = 'auto';
                    colorbar(app.UIAxesResultFieldBig);
                end
            elseif strcmp(app.ColorbarlimitsDropDown.Value,'manual')
                app.maxEditField.Visible = "on";
                app.minEditField.Visible = "on";
                app.maxEditFieldLabel.Visible = "on";
                app.minEditFieldLabel.Visible = "on";
                % Update the colorbar
                app.updateColorBar();
            end
        end

        % Value changed function: minEditField
        function minEditFieldValueChanged(app, event)
            app.updateColorBar();
        end

        % Value changed function: maxEditField
        function maxEditFieldValueChanged(app, event)
            app.updateColorBar();
        end

        % Value changed function: PlotaprofileCheckBox
        function PlotaprofileCheckBoxValueChanged(app, event)
            app.plotField();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.PorousLabMechanicalModelUIFigure = uifigure('Visible', 'off');
            app.PorousLabMechanicalModelUIFigure.Name = 'PorousLab - Mechanical Model';
            app.PorousLabMechanicalModelUIFigure.Icon = fullfile(pathToMLAPP, 'images', 'logo_porouslab_vertical_reduced.png');
            app.TabGroup = uitabgroup(app.PorousLabMechanicalModelUIFigure);
            app.DirichletBCsTab = uitab(app.TabGroup);
            app.DirichletBCsTab.Tooltip = {'Add Dirichlet boundary conditions'};
            app.DirichletBCsTab.Title = 'Dirichlet BCs';
            app.UIAxesDirichlet = uiaxes(app.DirichletBCsTab);
            app.SetdisplacementatLabel = uilabel(app.DirichletBCsTab);
            app.SetdisplacementatLabel.Text = 'Set displacement at';
            app.SelecttheborderDropDown = uidropdown(app.DirichletBCsTab);
            app.SelecttheborderDropDown.Items = {'left', 'right', 'top', 'bottom'};
            app.SelecttheborderDropDown.Visible = 'off';
            app.SelecttheborderDropDown.Value = 'left';
            app.SelecttheborderDropDownLabel = uilabel(app.DirichletBCsTab);
            app.SelecttheborderDropDownLabel.Visible = 'off';
            app.SelecttheborderDropDownLabel.Text = 'Select the border';
            app.UyEditFieldLabel = uilabel(app.DirichletBCsTab);
            app.UyEditFieldLabel.Text = 'Uy';
            app.UyEditField = uieditfield(app.DirichletBCsTab, 'numeric');
            app.UyEditField.Tooltip = {'Value of the prescribed vertical displacement'};
            app.UxEditFieldLabel = uilabel(app.DirichletBCsTab);
            app.UxEditFieldLabel.Text = 'Ux';
            app.UxEditField = uieditfield(app.DirichletBCsTab, 'numeric');
            app.UxEditField.Tooltip = {'Value of the prescribed horizontal displacement'};
            app.PrescribedvaluesLabel = uilabel(app.DirichletBCsTab);
            app.PrescribedvaluesLabel.Text = 'Prescribed values';
            app.xEditFieldLabel = uilabel(app.DirichletBCsTab);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.DirichletBCsTab, 'numeric');
            app.yEditFieldLabel = uilabel(app.DirichletBCsTab);
            app.yEditFieldLabel.Text = 'y';
            app.yEditField = uieditfield(app.DirichletBCsTab, 'numeric');
            app.ClearButton = uibutton(app.DirichletBCsTab, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Tooltip = {'Clear all prescribed Dirichlet boundary conditions'};
            app.ClearButton.Text = 'Clear';
            app.CheckFixedUx = uicheckbox(app.DirichletBCsTab);
            app.CheckFixedUx.Text = '';
            app.CheckFixedUy = uicheckbox(app.DirichletBCsTab);
            app.CheckFixedUy.Text = '';
            app.FixedLabel = uilabel(app.DirichletBCsTab);
            app.FixedLabel.Text = 'Fixed';
            app.DropDownDirichletBC = uidropdown(app.DirichletBCsTab);
            app.DropDownDirichletBC.Items = {'node', 'border'};
            app.DropDownDirichletBC.ValueChangedFcn = createCallbackFcn(app, @DropDownDirichletBCValueChanged, true);
            app.DropDownDirichletBC.Value = 'node';
            app.ApplyButton = uibutton(app.DirichletBCsTab, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Tooltip = {'Apply the Dirichlet boundary condition'};
            app.ApplyButton.Text = 'Apply';
            app.NeumannBCsTab = uitab(app.TabGroup);
            app.NeumannBCsTab.Tooltip = {'Add Neumann boundary conditions'};
            app.NeumannBCsTab.Title = 'Neumann BCs';
            app.UIAxes_Neumann = uiaxes(app.NeumannBCsTab);
            app.SetloadatLabel = uilabel(app.NeumannBCsTab);
            app.SetloadatLabel.Text = 'Set load at';
            app.LoadNLabel = uilabel(app.NeumannBCsTab);
            app.LoadNLabel.Text = 'Load (N)';
            app.ClearButton_Neumann = uibutton(app.NeumannBCsTab, 'push');
            app.ClearButton_Neumann.ButtonPushedFcn = createCallbackFcn(app, @ClearButton_NeumannPushed, true);
            app.ClearButton_Neumann.Tooltip = {'Clear all the Neumann boundary conditions'};
            app.ClearButton_Neumann.Text = 'Clear';
            app.SelecttheborderLabel = uilabel(app.NeumannBCsTab);
            app.SelecttheborderLabel.Visible = 'off';
            app.SelecttheborderLabel.Text = 'Select the border';
            app.BorderDropDown_Neumann = uidropdown(app.NeumannBCsTab);
            app.BorderDropDown_Neumann.Items = {'left', 'right', 'top', 'bottom'};
            app.BorderDropDown_Neumann.Visible = 'off';
            app.BorderDropDown_Neumann.Value = 'left';
            app.FyEditFieldLabel = uilabel(app.NeumannBCsTab);
            app.FyEditFieldLabel.Text = 'Fy';
            app.FyEditField = uieditfield(app.NeumannBCsTab, 'numeric');
            app.FyEditField.Tooltip = {'Force component at y-direction'};
            app.xEditField_2Label = uilabel(app.NeumannBCsTab);
            app.xEditField_2Label.Text = 'x';
            app.xEditField_Neumann = uieditfield(app.NeumannBCsTab, 'numeric');
            app.xEditField_Neumann.Tooltip = {'x-coordinate of the node'};
            app.yEditField_2Label = uilabel(app.NeumannBCsTab);
            app.yEditField_2Label.Text = 'y';
            app.yEditField_Neumann = uieditfield(app.NeumannBCsTab, 'numeric');
            app.yEditField_Neumann.Tooltip = {'y-coordinate of the node'};
            app.FxEditFieldLabel = uilabel(app.NeumannBCsTab);
            app.FxEditFieldLabel.Text = 'Fx';
            app.FxEditField = uieditfield(app.NeumannBCsTab, 'numeric');
            app.FxEditField.Tooltip = {'Force component at the x-direction'};
            app.ApplyButtonNeumann = uibutton(app.NeumannBCsTab, 'push');
            app.ApplyButtonNeumann.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonNeumannPushed, true);
            app.ApplyButtonNeumann.Text = 'Apply';
            app.DropDownNeumannBC = uidropdown(app.NeumannBCsTab);
            app.DropDownNeumannBC.Items = {'node', 'border'};
            app.DropDownNeumannBC.ValueChangedFcn = createCallbackFcn(app, @DropDownNeumannBCValueChanged, true);
            app.DropDownNeumannBC.Value = 'node';
            app.BodyforcesDropDownLabel = uilabel(app.NeumannBCsTab);
            app.BodyforcesDropDownLabel.Text = 'Body forces';
            app.BodyforcesDropDown = uidropdown(app.NeumannBCsTab);
            app.BodyforcesDropDown.Items = {'Yes', 'No'};
            app.BodyforcesDropDown.ValueChangedFcn = createCallbackFcn(app, @BodyforcesDropDownValueChanged, true);
            app.BodyforcesDropDown.Tooltip = {'Select if you want to consider the gravity forces'};
            app.BodyforcesDropDown.Value = 'No';
            app.PorousmediaTab = uitab(app.TabGroup);
            app.PorousmediaTab.Tooltip = {'Update the material properties'};
            app.PorousmediaTab.Title = 'Porous media';
            app.UITableMaterial = uitable(app.PorousmediaTab);
            app.UITableMaterial.ColumnName = {'Property'; 'Value'; 'Unit'};
            app.UITableMaterial.RowName = {};
            app.UITableMaterial.ColumnEditable = [false true false];
            app.UITableMaterial.Tag = 'MaterialTable';
            app.MaterialmodelDropDownLabel = uilabel(app.PorousmediaTab);
            app.MaterialmodelDropDownLabel.Text = 'Material model';
            app.MaterialmodelDropDown = uidropdown(app.PorousmediaTab);
            app.MaterialmodelDropDown.Items = {'Linear Elastic', 'von Mises', 'Drucker-Prager', 'Isotropic damage'};
            app.MaterialmodelDropDown.ValueChangedFcn = createCallbackFcn(app, @MaterialmodelDropDownValueChanged, true);
            app.MaterialmodelDropDown.Tooltip = {'Select the material model'};
            app.MaterialmodelDropDown.Value = 'Linear Elastic';
            app.UpdatematerialButton = uibutton(app.PorousmediaTab, 'push');
            app.UpdatematerialButton.ButtonPushedFcn = createCallbackFcn(app, @UpdatematerialButtonPushed, true);
            app.UpdatematerialButton.Tooltip = {'Update the material properties'};
            app.UpdatematerialButton.Text = 'Update material';
            app.AnalysisTab = uitab(app.TabGroup);
            app.AnalysisTab.Title = 'Analysis';
            app.AnalysistypeDropDown_2Label = uilabel(app.AnalysisTab);
            app.AnalysistypeDropDown_2Label.Text = 'Analysis type';
            app.AnalysistypeDropDown_2 = uidropdown(app.AnalysisTab);
            app.AnalysistypeDropDown_2.Items = {'Linear', 'Quasi-static'};
            app.AnalysistypeDropDown_2.ValueChangedFcn = createCallbackFcn(app, @AnalysistypeDropDown_2ValueChanged, true);
            app.AnalysistypeDropDown_2.Tooltip = {'Select the analysis type'};
            app.AnalysistypeDropDown_2.Value = 'Linear';
            app.UpdateanalysisButton = uibutton(app.AnalysisTab, 'push');
            app.UpdateanalysisButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateanalysisButtonPushed, true);
            app.UpdateanalysisButton.Tooltip = {'Update the analysis object'};
            app.UpdateanalysisButton.Text = 'Update analysis';
            app.RunButton = uibutton(app.AnalysisTab, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Tooltip = {'Run the analysis'};
            app.RunButton.Text = 'Run';
            app.MaximumloadratioEditFieldLabel = uilabel(app.AnalysisTab);
            app.MaximumloadratioEditFieldLabel.Visible = 'off';
            app.MaximumloadratioEditFieldLabel.Text = 'Maximum load ratio';
            app.MaximumloadratioEditField = uieditfield(app.AnalysisTab, 'numeric');
            app.MaximumloadratioEditField.ValueDisplayFormat = '%11.1f';
            app.MaximumloadratioEditField.Visible = 'off';
            app.MaximumloadratioEditField.Value = 1;
            app.MaximumnumberofstepsEditFieldLabel = uilabel(app.AnalysisTab);
            app.MaximumnumberofstepsEditFieldLabel.Visible = 'off';
            app.MaximumnumberofstepsEditFieldLabel.Text = 'Maximum number of steps';
            app.MaximumnumberofstepsEditField = uieditfield(app.AnalysisTab, 'numeric');
            app.MaximumnumberofstepsEditField.RoundFractionalValues = 'on';
            app.MaximumnumberofstepsEditField.Visible = 'off';
            app.MaximumnumberofstepsEditField.Value = 10;
            app.MaximumnumberofiterationsEditFieldLabel = uilabel(app.AnalysisTab);
            app.MaximumnumberofiterationsEditFieldLabel.Visible = 'off';
            app.MaximumnumberofiterationsEditFieldLabel.Text = 'Maximum number of iterations';
            app.MaximumnumberofiterationsEditField = uieditfield(app.AnalysisTab, 'numeric');
            app.MaximumnumberofiterationsEditField.Visible = 'off';
            app.MaximumnumberofiterationsEditField.Value = 20;
            app.InitialincrementEditFieldLabel = uilabel(app.AnalysisTab);
            app.InitialincrementEditFieldLabel.Visible = 'off';
            app.InitialincrementEditFieldLabel.Text = 'Initial increment';
            app.InitialincrementEditField = uieditfield(app.AnalysisTab, 'numeric');
            app.InitialincrementEditField.Visible = 'off';
            app.InitialincrementEditField.Value = 0.1;
            app.MaximumincrementEditFieldLabel = uilabel(app.AnalysisTab);
            app.MaximumincrementEditFieldLabel.Visible = 'off';
            app.MaximumincrementEditFieldLabel.Text = 'Maximum increment';
            app.MaximumincrementEditField = uieditfield(app.AnalysisTab, 'numeric');
            app.MaximumincrementEditField.Visible = 'off';
            app.MaximumincrementEditField.Value = 0.1;
            app.TargetnumberofiterationsEditFieldLabel = uilabel(app.AnalysisTab);
            app.TargetnumberofiterationsEditFieldLabel.Visible = 'off';
            app.TargetnumberofiterationsEditFieldLabel.Text = 'Target number of iterations';
            app.TargetnumberofiterationsEditField = uieditfield(app.AnalysisTab, 'numeric');
            app.TargetnumberofiterationsEditField.Visible = 'off';
            app.TargetnumberofiterationsEditField.Value = 4;
            app.NonlinearquasistaticanalysisparametersLabel = uilabel(app.AnalysisTab);
            app.NonlinearquasistaticanalysisparametersLabel.Visible = 'off';
            app.NonlinearquasistaticanalysisparametersLabel.Text = 'Nonlinear quasi-static analysis parameters';
            app.MethodDropDownLabel = uilabel(app.AnalysisTab);
            app.MethodDropDownLabel.Visible = 'off';
            app.MethodDropDownLabel.Text = 'Method';
            app.MethodDropDown = uidropdown(app.AnalysisTab);
            app.MethodDropDown.Items = {'Load Control', 'Cylindrical Arc Length', 'Generalized Displacement'};
            app.MethodDropDown.Visible = 'off';
            app.MethodDropDown.Value = 'Load Control';
            app.TextArea = uitextarea(app.AnalysisTab);
            app.TextArea.Value = {'**************** PorousLab ****************'};
            app.PostProcessTab = uitab(app.TabGroup);
            app.PostProcessTab.Title = 'Post-Process';
            app.UIAxesResultProfile = uiaxes(app.PostProcessTab);
            app.UIAxesResultProfile.Visible = 'off';
            app.UIAxesResultFieldSmall = uiaxes(app.PostProcessTab);
            app.UIAxesResultFieldSmall.Visible = 'off';
            app.UIAxesResultFieldBig = uiaxes(app.PostProcessTab);
            app.UIAxesResultFieldBig.Visible = 'off';
            app.FieldDropDownLabel = uilabel(app.PostProcessTab);
            app.FieldDropDownLabel.Text = 'Field';
            app.FieldDropDown = uidropdown(app.PostProcessTab);
            app.FieldDropDown.Items = {'None', 'Ux', 'Uy', 'Sx', 'Sy', 'Sxy', 'S1', 'S2', 'E1', 'PEMAG'};
            app.FieldDropDown.ValueChangedFcn = createCallbackFcn(app, @FieldDropDownValueChanged, true);
            app.FieldDropDown.Value = 'None';
            app.UITableResultProfile = uitable(app.PostProcessTab);
            app.UITableResultProfile.ColumnName = {'x'; 'y'};
            app.UITableResultProfile.RowName = {};
            app.UITableResultProfile.ColumnEditable = true;
            app.UITableResultProfile.CellEditCallback = createCallbackFcn(app, @UITableResultProfileCellEdit, true);
            app.UITableResultProfile.Visible = 'off';
            app.ExportmodelButton = uibutton(app.PostProcessTab, 'push');
            app.ExportmodelButton.ButtonPushedFcn = createCallbackFcn(app, @ExportmodelButtonPushed, true);
            app.ExportmodelButton.Tooltip = {'Export the model object'};
            app.ExportmodelButton.Text = 'Export model';
            app.ColorbarlimitsDropDownLabel = uilabel(app.PostProcessTab);
            app.ColorbarlimitsDropDownLabel.Visible = 'off';
            app.ColorbarlimitsDropDownLabel.Text = 'Colorbar limits';
            app.ColorbarlimitsDropDown = uidropdown(app.PostProcessTab);
            app.ColorbarlimitsDropDown.Items = {'default', 'manual'};
            app.ColorbarlimitsDropDown.ValueChangedFcn = createCallbackFcn(app, @ColorbarlimitsDropDownValueChanged, true);
            app.ColorbarlimitsDropDown.Visible = 'off';
            app.ColorbarlimitsDropDown.Value = 'default';
            app.minEditFieldLabel = uilabel(app.PostProcessTab);
            app.minEditFieldLabel.Visible = 'off';
            app.minEditFieldLabel.Text = 'min';
            app.minEditField = uieditfield(app.PostProcessTab, 'numeric');
            app.minEditField.ValueChangedFcn = createCallbackFcn(app, @minEditFieldValueChanged, true);
            app.minEditField.Visible = 'off';
            app.maxEditFieldLabel = uilabel(app.PostProcessTab);
            app.maxEditFieldLabel.Visible = 'off';
            app.maxEditFieldLabel.Text = 'max';
            app.maxEditField = uieditfield(app.PostProcessTab, 'numeric');
            app.maxEditField.ValueChangedFcn = createCallbackFcn(app, @maxEditFieldValueChanged, true);
            app.maxEditField.Visible = 'off';
            app.maxEditField.Value = 1;
            app.PlotaprofileCheckBox = uicheckbox(app.PostProcessTab);
            app.PlotaprofileCheckBox.ValueChangedFcn = createCallbackFcn(app, @PlotaprofileCheckBoxValueChanged, true);
            app.PlotaprofileCheckBox.Visible = 'off';
            app.PlotaprofileCheckBox.Text = 'Plot a profile';
            % Show the figure after all components are created
            app.PorousLabMechanicalModelUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mechanicalModel(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PorousLabMechanicalModelUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PorousLabMechanicalModelUIFigure)
        end
    end
end