classdef regMeshGeneration < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        PorousLabMeshgenerationPanel  matlab.ui.container.Panel
        NxEditField                   matlab.ui.control.NumericEditField
        NxEditField_2Label            matlab.ui.control.Label
        NumberofsubdivisionsLabel     matlab.ui.control.Label
        DimensionsLabel               matlab.ui.control.Label
        AddmeshButton                 matlab.ui.control.Button
        NyEditField                   matlab.ui.control.NumericEditField
        NyEditFieldLabel              matlab.ui.control.Label
        LyEditField                   matlab.ui.control.NumericEditField
        LyEditFieldLabel              matlab.ui.control.Label
        LxEditField                   matlab.ui.control.NumericEditField
        LxEditFieldLabel              matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        mdl;    % Model object
        femplot = FEMPlot();
    end
    
    methods (Access = private)
        
        %------------------------------------------------------------------
        % Plot the mesh
        function plotMesh(app)
            patch(app.UIAxes, 'Faces', app.mdl.ELEM, ...
                      'Vertices', app.mdl.NODE,...
                      'FaceColor', 'g', ...  
                      'LineWidth', 0.7, ...
                      'LineStyle', '-', ...
                      'Marker', 'None');
            bbox = app.getBoundingBox();
            xlim(app.UIAxes, [bbox(1) bbox(3)]);
            ylim(app.UIAxes, [bbox(2) bbox(4)]);
            axis(app.UIAxes, 'equal');
        end

        %------------------------------------------------------------------
        % Set the mesh to the model
        function setMesh(app)
            % Get dimensions
            Lx = app.LxEditField.Value;
            Ly = app.LyEditField.Value;

            % Get sub-divisions
            Nx = app.NxEditField.Value;
            Ny = app.NyEditField.Value;
            
            % Generate regular mesh
            [node, elem] = regularMesh(Lx, Ly, Nx, Ny);

            % Set mesh to model
            app.mdl.setMesh(node, elem);
        end

        %------------------------------------------------------------------
        % Computes the bounding box of the model with a tolerance.
        function bbox = getBoundingBox(app)
            minX = Inf; minY = Inf; maxX = -Inf; maxY = -Inf;
            for el = 1:size(app.mdl.ELEM,1)
                minX = min(minX,min(app.mdl.NODE(:,1)));
                minY = min(minY,min(app.mdl.NODE(:,2)));
                maxX = max(maxX,max(app.mdl.NODE(:,1)));
                maxY = max(maxY,max(app.mdl.NODE(:,2)));
            end
            tolx = 0.10*abs(max(app.mdl.NODE(:,1)) - min(app.mdl.NODE(:,1)));
            toly = 0.10*abs(max(app.mdl.NODE(:,1)) - min(app.mdl.NODE(:,1)));
            bbox(1) = minX - tolx;
            bbox(2) = minY - toly;
            bbox(3) = maxX + tolx;
            bbox(4) = maxY + toly;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mdl)
            app.mdl = mdl;
            app.setMesh();
            app.plotMesh();
        end

        % Button pushed function: AddmeshButton
        function AddmeshButtonPushed(app, event)

            app.setMesh();

            % Initialize the model configuration app
            if strcmp(app.mdl.physics,'M')
                mechanicalModel(app.mdl);
            end

            % Delete this app
            delete(app);
        end

        % Value changed function: LxEditField
        function LxEditFieldValueChanged(app, event)
            app.setMesh();
            app.plotMesh();
        end

        % Value changed function: LyEditField
        function LyEditFieldValueChanged(app, event)
            app.setMesh();
            app.plotMesh();
        end

        % Value changed function: NxEditField
        function NxEditFieldValueChanged(app, event)
            app.setMesh();
            app.plotMesh();
        end

        % Value changed function: NyEditField
        function NyEditFieldValueChanged(app, event)
            app.setMesh();
            app.plotMesh();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PorousLabMeshgenerationPanel = uipanel(app.UIFigure);
            app.PorousLabMeshgenerationPanel.Title = 'PorousLab - Mesh generation';
            app.UIAxes = uiaxes(app.PorousLabMeshgenerationPanel);
            app.LxEditFieldLabel = uilabel(app.PorousLabMeshgenerationPanel);
            app.LxEditFieldLabel.Text = 'Lx';
            app.LxEditField = uieditfield(app.PorousLabMeshgenerationPanel, 'numeric');
            app.LxEditField.ValueChangedFcn = createCallbackFcn(app, @LxEditFieldValueChanged, true);
            app.LxEditField.Value = 3;
            app.LyEditFieldLabel = uilabel(app.PorousLabMeshgenerationPanel);
            app.LyEditFieldLabel.Text = 'Ly';
            app.LyEditField = uieditfield(app.PorousLabMeshgenerationPanel, 'numeric');
            app.LyEditField.ValueChangedFcn = createCallbackFcn(app, @LyEditFieldValueChanged, true);
            app.LyEditField.Value = 1;
            app.NyEditFieldLabel = uilabel(app.PorousLabMeshgenerationPanel);
            app.NyEditFieldLabel.Text = 'Ny';
            app.NyEditField = uieditfield(app.PorousLabMeshgenerationPanel, 'numeric');
            app.NyEditField.Limits = [1 200];
            app.NyEditField.RoundFractionalValues = 'on';
            app.NyEditField.ValueDisplayFormat = '%11d';
            app.NyEditField.ValueChangedFcn = createCallbackFcn(app, @NyEditFieldValueChanged, true);
            app.NyEditField.Value = 2;
            app.AddmeshButton = uibutton(app.PorousLabMeshgenerationPanel, 'push');
            app.AddmeshButton.ButtonPushedFcn = createCallbackFcn(app, @AddmeshButtonPushed, true);
            app.AddmeshButton.Text = 'Add mesh';
            app.DimensionsLabel = uilabel(app.PorousLabMeshgenerationPanel);
            app.DimensionsLabel.Text = 'Dimensions';
            app.NumberofsubdivisionsLabel = uilabel(app.PorousLabMeshgenerationPanel);
            app.NumberofsubdivisionsLabel.Text = 'Number of sub-divisions';
            app.NxEditField_2Label = uilabel(app.PorousLabMeshgenerationPanel);
            app.NxEditField_2Label.Text = 'Nx';
            app.NxEditField = uieditfield(app.PorousLabMeshgenerationPanel, 'numeric');
            app.NxEditField.Limits = [1 200];
            app.NxEditField.RoundFractionalValues = 'on';
            app.NxEditField.ValueDisplayFormat = '%11d';
            app.NxEditField.ValueChangedFcn = createCallbackFcn(app, @NxEditFieldValueChanged, true);
            app.NxEditField.Value = 2;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = regMeshGeneration(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end